classdef FaceVoiceRecogition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ControlPanel               matlab.ui.container.Panel
        RecordButton               matlab.ui.control.Button
        PlotButton                 matlab.ui.control.Button
        SpectrumButton             matlab.ui.control.Button
        Load                       matlab.ui.control.Button
        MFCC                       matlab.ui.control.Button
        ClearButton                matlab.ui.control.Button
        Endcut                     matlab.ui.control.Button
        Sound                      matlab.ui.control.Button
        Excel                      matlab.ui.control.Button
        PreemphCheckBox            matlab.ui.control.CheckBox
        Image                      matlab.ui.control.Image
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        Label_3                    matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        IdentificationPanel        matlab.ui.container.Panel
        Train                      matlab.ui.control.Button
        MultiTest                  matlab.ui.control.Button
        Save                       matlab.ui.control.Button
        SingleTest                 matlab.ui.control.Button
        PasswordDetect             matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        SpeakerIDEditFieldLabel    matlab.ui.control.Label
        SpeakerIDEditField         matlab.ui.control.NumericEditField
        CentroidEditFieldLabel     matlab.ui.control.Label
        CentroidEditField          matlab.ui.control.NumericEditField
        NofSpeakerEditFieldLabel   matlab.ui.control.Label
        NofSpeakerEditField        matlab.ui.control.NumericEditField
        PasswordHTML               matlab.ui.control.HTML
        password_label             matlab.ui.control.Label
        TimeLabel                  matlab.ui.control.Label
        Time                       matlab.ui.control.NumericEditField
        ControlFacePanel           matlab.ui.container.Panel
        ChonAnh                    matlab.ui.control.Button
        NhanDang                   matlab.ui.control.Button
        XoaAnh                     matlab.ui.control.Button
        Clear                      matlab.ui.control.Button
        WebcamButton               matlab.ui.control.Button
        LoadnhButton               matlab.ui.control.Button
        ExcelButton                matlab.ui.control.Button
        DngWebcamButton            matlab.ui.control.Button
        TrainFacePanel             matlab.ui.container.Panel
        TrainButton                matlab.ui.control.Button
        SnhTrainEditFieldLabel     matlab.ui.control.Label
        NumTrainImg                matlab.ui.control.NumericEditField
        SEigenValueEditFieldLabel  matlab.ui.control.Label
        NumEigenVal                matlab.ui.control.NumericEditField
        ChupAnhTrain               matlab.ui.control.Button
        TrainFromNoLabel           matlab.ui.control.Label
        From                       matlab.ui.control.NumericEditField
        ToNoLabel                  matlab.ui.control.Label
        To                         matlab.ui.control.NumericEditField
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes1                    matlab.ui.control.UIAxes
        TextArea_2Label            matlab.ui.control.Label
        TextArea_2                 matlab.ui.control.TextArea
        HTHNGNHNDNGNGIBNGKHUNMTVGINGNILabel  matlab.ui.control.Label
        LampPanel                  matlab.ui.container.Panel
        Lamp_3                     matlab.ui.control.Lamp
        Lamp                       matlab.ui.control.Lamp
        Lamp_2                     matlab.ui.control.Lamp
        UIAxes4                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Voice
        myVoice
        Fs% Description
        myVoice_preem % Description
        SpeakerID % Description
        ceptrum % Description
        codebook % Description
        
        trainpath % Description
        testpath % Description
        NumberofSpearker % Description
        centroid % Description
        Number % Description
        distance2excel % Description
        Excelpath % Description
        word % Description
        trainpasswordpath % Description
        codebookpass % Description
        password
        
        %Face
        M % Description
        N % Description
        m % Description
        Ppca % Description
        T % Description
        img % Description
        resultimg % Description
        trainpathface % Description
        testpathface % Description
        % Description
        EigenValue % Description
        NumTrain % Description
        cam % Description
        
        distance % Description
        Excelpath2 % Description
        go % Description
        flag2 % Description
        Times % Description
        From1 % Description
        To1 % Description
        ChinhChu % Description
        NhapExcel % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %PathDat
            %PathVoice
            
%             %             app.trainpath =         "C:\Users\Hi\Desktop\LVTN\GUI_matlab\train_20_final\1\";
%             %             app.trainpath =         "F:\LVTN\Face_Voice_Recogition\train_20_final\4\";
%             %             app.trainpasswordpath = "C:\Users\Hi\Desktop\LVTN\GUI_matlab\Password1\data\Password\";
%             app.trainpasswordpath = "F:\LVTN\Face_Voice_Recogition\Password\Password\";
%             app.testpath  =         "C:\Users\Hi\Desktop\LVTN\GUI_matlab\test\";
%             app.Excelpath =         "F:\LVTN\Face_Voice_Recogition\data_voice.xlsx";
%             %Turn off lamp
%             app.Lamp.Color = [0.90,0.90,0.90];
%             %             app.Label_Lamp.Text = "Not Active";
%             
%             %PathFace
%             app.trainpathface = "F:\LVTN\Face_Voice_Recogition\TrainDB\";
%             app.testpathface  = "F:\LVTN\Face_Voice_Recogition\TestDB\";
%             app.Excelpath2 =  "F:\LVTN\Face_Voice_Recogition\data_face.xlsx";
            
            %             %PathLuan
            %             app.trainpath =         "C:\Users\Hi\Desktop\LVTN\GUI_matlab\train_20_final\1\";
            %             %             app.trainpath =         "F:\LVTN\Face_Voice_Recogition\train_20_final\0\";
            %             %             app.trainpasswordpath = "C:\Users\Hi\Desktop\LVTN\GUI_matlab\Password1\data\Password\";
            app.trainpasswordpath = "C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\Password\Password\";
            app.testpath  =         "C:\Users\Hi\Desktop\LVTN\GUI_matlab\test\";
            app.Excelpath =         "C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\data_voice.xlsx";
            %Turn off lamp
            app.Lamp.Color = [0.90,0.90,0.90];
            app.Lamp_2.Color = [0.90,0.90,0.90];
            app.Lamp_3.Color = [0.90,0.90,0.90];
            %             app.Label_Lamp.Text = "Not Active";
            
            %PathFace
            app.trainpathface = "C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\TrainDB\";
            app.testpathface  = "C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\TestDB\";
            app.Excelpath2 =  "C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\data_face.xls";
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            Fs = 16000 ;
            nBits = 8 ;
            nChannels = 1 ;
            % default audio input device
            recObj = audiorecorder(Fs,nBits,nChannels,0);
            %display
            app.Label_2.Text = sprintf('s%d.wav',app.SpeakerID);
            app.Label.Text = "Start of recording after 3 ...";
            pause(1);
            app.Label.Text = "Start of recording after 2 ...";
            pause(1);
            app.Label.Text = "Start of recording after 1 ...";
            pause(1);
            app.Label.Text = "Start of Recording";
            recordblocking(recObj,app.Times);
            app.Label.Text = "End of Recording";
            pause(1);
            %record
            y  = getaudiodata(recObj);
            audio_play = audioplayer(y,Fs);
            %playback
            sound(y,Fs);
            %save to folder
                        audiopath = ("C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\Audio\");
%             audiopath = ("F:\LVTN\Face_Voice_Recogition\");
            savepath = sprintf("%ss%d.wav",audiopath,app.SpeakerID);
            audiowrite(savepath,y,Fs);
            app.Label.Text = "Saved voice";
            
            app.myVoice = y;
            app.Fs      = Fs;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            close all;
            plot(app.UIAxes,app.myVoice,'r');
            app.Label.Text = string(app.Fs);
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            [m,f] = spectrum(app.myVoice,app.Fs);
            plot(app.UIAxes,f,m);
            xlabel(app.UIAxes,'f (Hz)');
            title(app.UIAxes,'Spectrum');
        end

        % Value changed function: PreemphCheckBox
        function PreemphCheckBoxValueChanged(app, event)
            value = app.PreemphCheckBox.Value;
            close all;
            myVoice_preemph = pre_emph(app.myVoice);
            app.myVoice_preem = myVoice_preemph;
            title(app.UIAxes,'');
            if value == true
                close all;
                plot(app.UIAxes,myVoice_preemph,'r');
                hold (app.UIAxes,'on');
                plot(app.UIAxes,app.myVoice,'b');
                hLegend = legend(app.UIAxes,"Preemphasis","Original");
                
            else
                close all;
                legend(app.UIAxes,'off');
                plot(app.UIAxes,app.myVoice,'b');
                
                
            end
            hold (app.UIAxes,'off');
            app.myVoice = myVoice_preemph;
        end

        % Value changed function: SpeakerIDEditField
        function SpeakerIDEditFieldValueChanged(app, event)
            value = app.SpeakerIDEditField.Value;
            app.SpeakerID = value;
        end

        % Button pushed function: MFCC
        function MFCCButtonPushed(app, event)
            
            close all;
            
            s = app.myVoice;
            s(s==0)=[];
            s=pre_emph(s);
            s=s/max(abs(s));
            
            app.ceptrum = mymfcc(s,app.Fs);
            plot(app.UIAxes,app.ceptrum);
            app.UIAxes.Title.String = ("Accounstic vector");
        end

        % Button pushed function: Sound
        function SoundButtonPushed(app, event)
            sound(app.myVoice,app.Fs);
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            
            [file,~] = uigetfile('*.wav','Select your audio');
            [app.myVoice,app.Fs] = audioread(file);
            drawnow;
            figure(app.UIFigure);
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Button pushed function: Train
        function TrainButtonPushed(app, event)
            
            %             k=app.CentroidEditField.Value;
            %             k=k*2;
            %             codebook = vqlbg(app.ceptrum,k,1);
            %             close all;
            %             app.codebook = codebook;
            %             plot(app.UIAxes,codebook);
            %             app.UIAxes.Title.String = ("Accounstic vector after VQ");
            %             filename = "database.mat";
            %             save(filename);
            n = app.NumberofSpearker;
            
            k = app.centroid;                         % number of centroids required
            %k=k*2;
            for i = 1:n                  % train a VQ codebook for each speaker
                file = sprintf('%ss%d_4.wav', app.trainpath, i);
                %sprintf(file);
                app.TextArea.Value = [string(app.TextArea.Value);"Create codebook for each audio in train folder:";file];
                
                [s, fs] = audioread(file);
                s(s==0)=[];
                s = pre_emph(s);
                s=s/max(abs(s));
                
                v = mymfcc(s, fs);            % Compute MFCC'
                code{i} = vqlbg(v, k,2);      % Train VQ codebook
            end
            
            app.codebook = code;
            
        end

        % Value changed function: CentroidEditField
        function CentroidEditFieldValueChanged(app, event)
            value = app.CentroidEditField.Value;
            app.centroid = value;
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            %              trainpath = ("C:\Users\Hi\Desktop\LVTN\GUI_matlab\train\");
            file = sprintf('%ss%d_9.wav', app.trainpath, app.SpeakerID);
            audiowrite(file,app.myVoice,app.Fs);
            app.TextArea.Value = [string(app.TextArea.Value);"Create file audio in train folder:";file];
        end

        % Button pushed function: MultiTest
        function MultiTestButtonPushed(app, event)
            n = app.NumberofSpearker;
            code =app.codebook;
            
            for k = 1:n                     % read test sound file of each speaker
                file = sprintf('%ss%d.wav', app.testpath, k);
                [s, fs] = audioread(file);
                s(s==0)=[];
                v = mymfcc(s, fs);            % Compute MFCC's
                
                distmin = inf;
                k1 = 0;
                
                for l = 1:length(code)      % each trained codebook, compute distortion
                    d = disteu(v, code{l},2);
                    dist = sum(min(d,[],2)) / size(d,1);
                    
                    if dist < distmin
                        distmin = dist;
                        k1 = l;
                    end
                end
                
                msg = sprintf('Speaker %d matches with speaker %d', k, k1);
                app.TextArea.Value = [string(app.TextArea.Value);msg];
            end
            
        end

        % Button pushed function: SingleTest
        function SingleTestButtonPushed(app, event)
            s = app.myVoice;
            fs =app.Fs;
            v = app.ceptrum;
            code = app.codebook;
            n = app.NumberofSpearker;
            %     load("database.dat","-mat");
            
            
            distmin = inf;
            k1 = 0;
            
            for l = 1:length(code)      % each trained codebook, compute distortion
                d = disteu(v, code{l},2);
                dist = sum(min(d,[],2)) / size(d,1);
                
                app.distance2excel(l) = dist;
                
                file = sprintf('%ss%d_4.wav', app.trainpath, l);
                app.TextArea.Value = [string(app.TextArea.Value);file;"Distance:";num2str(dist)];
                
                
                if dist < distmin
                    distmin = dist;
                    k1 = l;
                    
                end
                
            end
            
            
            %    end
            message = sprintf("Matches with speaker %d",k1);
            app.TextArea.Value = [string(app.TextArea.Value);message];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TextArea.Value = '';
            app.Lamp.Color = [0.90,0.90,0.90];
            app.Lamp_2.Color = [0.90,0.90,0.90];
            %             app.Lamp_3.Color = [0.90,0.90,0.90];
        end

        % Value changed function: NofSpeakerEditField
        function NofSpeakerEditFieldValueChanged(app, event)
            value = app.NofSpeakerEditField.Value;
            app.NumberofSpearker =value;
        end

        % Callback function
        function NumberEditFieldValueChanged(app, event)
            value = app.NumberEditField.Value;
            app.Number = value;
            
            
        end

        % Button pushed function: Excel
        function ExcelButtonPushed(app, event)
            Number_of_sheet = 1;
            Begin_range      = 'B418';
            
            writematrix(app.distance2excel,app.Excelpath,'sheet',Number_of_sheet,'Range',Begin_range);
            
            message = sprintf("Write to Excel %s, sheet: %d,begin address: %s",app.Excelpath,Number_of_sheet,Begin_range);
            app.TextArea.Value = [string(app.TextArea.Value);message];
        end

        % Button pushed function: Endcut
        function EndcutButtonPushed(app, event)
            %% caculate endpoint
            x = app.myVoice; fs=app.Fs;                 % Read data file
            x=x/max(abs(x));                        %  Amplitude normalization
            N=length(x);                            %  Take the signal length
            time=(0:N-1)/fs;                        %  calculating time
            figure;
            subplot 311
            plot(time,x,'k');
            title('Endpoint Detection of Double Threshold Method');
            ylabel('Amplitude'); axis([0 max(time) -1 1]);
            xlabel('Time/s');
            
            wlen=256; inc=90;                       %  Framing parameters
            IS=0.1; overlap=wlen-inc;               %  Set upIS
            NIS=fix((IS*fs-wlen)/inc +1);           %  CalculationNIS
            fn=fix((N-wlen)/inc)+1;                 %  Find the number of frames
            frameTime=FrameTimeC(fn, wlen, inc, fs);%  Calculate the time corresponding to each frame
            
            [voiceseg,vsl,~,~,amp,zcr]=vad_ezm1(x,wlen,inc,NIS);  %  Endpoint detection
            
            %% plot
            subplot 312
            plot(frameTime,amp,'k');
            ylim([min(amp) max(amp)])
            title('Short-term energy');
            ylabel('Amplitude');
            xlabel('Time/s');
            subplot 313
            plot(frameTime,zcr,'k');
            ylim([min(zcr) max(zcr)])
            title('Short time zero crossing rate');
            ylabel('Amplitude');
            xlabel('Time/s');
            
            
            % % caculate matrix for word
            % for j= 1 :vsl
            %     nx1=voiceseg(j).begin;
            %     nx2=voiceseg(j).end;
            %     nxl=voiceseg(j).duration;
            %
            %     s1 = frameTime(nx1)*fs; %begin in sample
            %     s2 = frameTime(nx2)*fs; %end in sample
            %     s3 = frameTime(nxl)*fs; %duration in sample
            %
            %     a(j) = (floor(s2-s1)+1);
            %     disp(a);
            %
            % end
            % % create word matrix
            %     max_voiceseg = max(a,[],'all');
            %     disp (max_voiceseg);
            %     word = zeros(max_voiceseg,vsl);
            
            for k=1 : vsl                           %  Draw the starting and ending points
                
                subplot 311
                
                %caculate for plotting in time
                nx1=voiceseg(k).begin;
                nx2=voiceseg(k).end;
                nxl=voiceseg(k).duration;
                
                %caculate for sample
                s1 = frameTime(nx1)*fs; %begin in sample
                s2 = frameTime(nx2)*fs; %end in sample
                s3 = frameTime(nxl)*fs; %duration in sample
                
                
                % plot audio
                line([frameTime(nx1) frameTime(nx1)],[-1.5 1.5],'color','r','LineStyle','-');
                line([frameTime(nx2) frameTime(nx2)],[-1.5 1.5],'color','b','LineStyle','--');
                subplot 312
                line([frameTime(nx1) frameTime(nx1)],[min(amp) max(amp)],'color','r','LineStyle','-');
                line([frameTime(nx2) frameTime(nx2)],[min(amp) max(amp)],'color','b','LineStyle','--');
                subplot 313
                line([frameTime(nx1) frameTime(nx1)],[min(zcr) max(zcr)],'color','r','LineStyle','-');
                line([frameTime(nx2) frameTime(nx2)],[min(zcr) max(zcr)],'color','b','LineStyle','--');
                
                word{k} = x(s1:s2);
            end
            
            %save to properties
            app.word = word;
            
            %plot audio after cut
            figure;
            for k = 1:vsl
                subplot(3,2,k);
                plot(word{k},'k');
                title_speech=sprintf('WORD NUMBER %d',k);
                title(title_speech);
            end
            
            % timeElapsed = toc;
            % disp(timeElapsed);
            
        end

        % Button pushed function: PasswordDetect
        function PasswordDetectButtonPushed(app, event)
            %% Train codebook password 0-9
            n = app.NumberofSpearker;
            k = app.centroid;                         % number of centroids required
            %             password = [0 3 7 2 5 6];
            
            
            %k=k*2;
            %             for i = 1:n                  % train a VQ codebook for each speaker
            %                 file = sprintf('%ss%d.wav',app.trainpasswordpath, i);
            %                 %sprintf(file);
            %                 %                     app.TextArea.Value = [string(app.TextArea.Value);"Create codebook for each audio in train folder:";file];
            %
            %                 [s, fs] = audioread(file);
            %                 s(s==0)=[];
            %                 s=s/max(abs(s));
            %                 %                     s = pre_emph(s);
            %                 v = mymfcc(s, fs);            % Compute MFCC'
            %                 code{i} = vqlbg(v, k,2);      % Train VQ codebook
            %             end
            %             save('voice.mat','code');
            %             disp('xong');
%             load("F:\LVTN\Face_Voice_Recogition\voice.mat");
load("C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\voice.mat");
            %     app.codebookpass = code;
            %% Detect password
            
            %     code = app.codebookpass;
            fs =app.Fs;
            word = app.word;
            
            %eliminate zero elements
            
            for j = 1:length(word)
                word{j}(word{j}==0)= []
            end
            
            %             k2 = zeros(1,length(word));
            %             distmin_word = zeros(1,length(word));
            tic
            %caculate MFCC for each voiceseg
            for ll = 1:length(word)
                v = mymfcc(word{ll},fs);
                
                distmin = inf;
                k1 = 0;
                
                for l = 1:length(code)      % each trained codebook, compute distortion
                    d = disteu(v, code{l},2);
                    dist = sum(min(d,[],2)) / size(d,1);
                    
                    %                 app.distance2excel(l) = dist;
                    
                    file = sprintf('%ss%d.wav', app.trainpasswordpath, l);
                    %                     app.TextArea.Value = [string(app.TextArea.Value);file;"Distance:";num2str(dist)];
                    
                    if dist < distmin
                        distmin = dist;
                        k1 = l;
                    end
                end
                
                %          message = sprintf("Matches with %d",k2);
                %          disp(k1);
                k2(ll) = k1-1;
                distmin_word(ll) = distmin;
                %                 message = sprintf("Min distance of word: %s",num2str(distmin_word));
                %                 message = sprintf(" %d",distmin_word);
                %                 app.TextArea.Value = [string(app.TextArea.Value);message];
                message = sprintf("Matches with %d",(k1-1));
                app.TextArea.Value = [string(app.TextArea.Value);message];
                
            end
            
            %So sanh so dau
            x = app.password(1)+1;
            file1 = sprintf('%ss%d.wav',app.trainpasswordpath, (app.password(1)+1));
            disp(x);
            %Khoang cach
            % each trained codebook, compute distortion
            v1 = mymfcc(word{1},fs);
            d1 = disteu(v1, code{x},2);
            dist1 = sum(min(d1,[],2)) / size(d1,1);
            disp(dist1);
            
            %                 app.distance2excel(l) = dist;
            
            
            app.TextArea.Value = [string(app.TextArea.Value);file1;"Distance:";num2str(dist1)];
            flag2=checkvoice(dist1,(app.password(1)));
            disp(flag2);
            
            %                          so sanh voice chinh chu
            %                         flag1 = checkvoice(distmin_word,k2);
            %
            %             So sanh password
            [~,flag] = checkpass(k2,app.password);
            
            if (flag)
                app.TextArea.Value = [string(app.TextArea.Value);'Dung mat khau'];
                app.Lamp.Color     = [0 1 0]; %Green
                
            else
                app.TextArea.Value = [string(app.TextArea.Value);'Sai mat khau'];
                app.Lamp.Color     = [1 0 0]; %Red
            end
            
            %             Ket hop pass and voice
            
            if (flag2&flag)
                app.TextArea.Value = [string(app.TextArea.Value);'Dung nguoi noi va mat khau'];
                app.Lamp.Color     = [0 1 0]; %Green
            elseif (flag2)
                app.TextArea.Value = [string(app.TextArea.Value);'Dung nguoi noi nhung sai mat khau'];
                app.Lamp.Color     = [1 1 0]; %Yellow
            elseif (flag2 == 0)
                app.TextArea.Value = [string(app.TextArea.Value);'Khong phai chinh chu'];
                app.Lamp.Color     = [1 0 0]; %Red
            end
            
            % Ket hop 3 buoc bao mat
            
            if (flag2&flag&app.flag2)
                app.Lamp_2.Color = [0 1 0]; %Green
                app.Label.Text = "Da nhan dien dung giong noi va khuon mat cua chinh chu"
            else
                app.Lamp_2.Color = [1 0 0];
                app.Label.Text = "Khong du cac buoc xac thuc";
            end
            
            time = toc;
            disp(time);
            
            
        end

        % Callback function
        function PasswordEditFieldValueChanged(app, event)
            value = app.PasswordEditField.Value;
            app.password = num2str(value) - '0';
            
        end

        % Callback function
        function PasswordEditFieldValueChanged2(app, event)
            value = app.PasswordEditField.Value;
            
            app.password = num2str(value) - '0';
            %             app.TextArea.Value = [string(app.TextArea.Value);app.password];
        end

        % Data changed function: PasswordHTML
        function PasswordHTMLDataChanged(app, event)
            app.password = app.PasswordHTML.Data;
            %             app.Label_2.Text = app.password;
            app.password = num2str(app.password) - '0';
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: WebcamButton
        function WebcamButtonPushed(app, event)
            app.go = 1;
            
            while app.go
                app.cam = webcam;
                frame = snapshot(app.cam);
                im = image(app.UIAxes3, zeros(size(frame),'uint8'));
                axis(app.UIAxes3,'image');
                
                preview(app.cam,im);
                pause;
            end
        end

        % Button pushed function: ChonAnh
        function ChonAnhButtonPushed(app, event)
            %             [filename,pathname]=uigetfile('*.*','Chon anh dau vao');
            %
            %
            %             filewithpath=strcat(pathname,filename);
            %             drawnow;
            %             figure(app.UIFigure);
            
            counter = 1;
            %             cam = webcam;
            app.cam.Resolution = '640x480';
            image = snapshot(app.cam);
            %             savename = strcat('C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\TestDB\',num2str(counter),'.jpg');
%             savename = strcat('F:\LVTN\Face_Voice_Recogition\TestDB\',num2str(counter),'.jpg');
savename = strcat('C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\TestDB\',num2str(counter),'.jpg');
            imwrite(image,savename);
            %             counter = counter+1;
            
            %             app.img=imread('C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\TestDB\1.jpg');
            app.img=imread('C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\TestDB\1.jpg');
            img = app.img;
            
            FaceDetect = vision.CascadeObjectDetector;
            FaceDetect.MergeThreshold = 7 ;
            BB = step(FaceDetect, img);
            if isempty(BB)
                app.TextArea_2.Value = [string(app.TextArea_2.Value);'Khong co guong mat'];
            else
                for i = 1 : size(BB, 1)
                    J = imcrop(img, BB(i, :));
                    
                end
                
                %                 app.img = J;
                imshow(J,'parent',app.UIAxes1);
            end
        end

        % Button pushed function: NhanDang
        function NhanDangButtonPushed(app, event)
            %             M=app.M;
            %             N=app.N;
            load('face.mat');
            app.M= M;
            app.N=N;
            app.Ppca=Ppca;
            app.T=t;
            app.m=m;
            
            img = app.img;
            
            FaceDetect = vision.CascadeObjectDetector;
            FaceDetect.MergeThreshold = 7 ;
            BB = step(FaceDetect, img);


            for i = 1 : size(BB, 1)
                j = imcrop(img, BB(i, :));
                
            end
            
            
            n=app.NumTrain;
            j=rgb2gray(j);
            j=imresize(j,[app.M,app.N]);
            j=double(reshape(j,[1,app.M*app.N]));
            imgpca=(j-app.m)*app.Ppca; %chieu anh goc ban dau len ko gian PCA
            distarray = zeros(n,1); %khoi tao mang cho viec so sanh
            for i=1:n
                distarray(i)=sum(abs(app.T(i,:)-imgpca)); %tim khoang cach giua anh train va anh dau vao
                
                
            end
            %             min_dist = min(distarray);
            %             max_dist = max(distarray);
            flag2 = 0;
            for i=1:n
                %                 distarray_1(i)=(distarray(i)-min_dist)/(max_dist-min_dist);
                
                file = sprintf('%d', distarray(i));
                %                 app.TextArea_2.Value = [string(app.TextArea_2.Value);i;"Khoang cach giua test và train:";file];
                app.distance(i) = distarray(i);
                
            end
            
            
            [result,indx]=min(distarray); %tim duoc anh match tu khoang cach nho nhat
            app.TextArea_2.Value = [string(app.TextArea_2.Value);i;"Khoang cach giua test và train nho nhat: ";result];
            if result<= 14000
                
                %                 a = sprintf("Accept this person");
                %                 app.TextArea_2.Value = [string(app.TextArea_2.Value);a];
                flag2 = 1;
                %                 app.Lamp_3.Color = [0 1 0]; %Green
            else
                %                 a = sprintf("Reject this person");
                %                 app.TextArea_2.Value = [string(app.TextArea_2.Value);a];
                flag2 =0;
                %                 app.Lamp_3.Color = [1 0 0];%Red
            end
            
            resultimg = imread(sprintf("%s%d.jpg",app.trainpathface,indx));
            imgGrayTrain = rgb2gray(resultimg);
            app.flag2 = flag2;
            s = sprintf("Anh train thu %d",indx);
            app.TextArea_2.Value = [string(app.TextArea_2.Value);s];
            
            if flag2 ~= 0
                detectorFace=vision.CascadeObjectDetector();
                BB=step(detectorFace,imgGrayTrain);
                for i = 1 : size(BB, 1)
                    J = imcrop(resultimg, BB(i, :));
                    
                end
                imshow(J,'parent',app.UIAxes4);
%                 detpic2=insertObjectAnnotation(resultimg,'Rectangle',boundingbox,'Le Tien Dat');
%                 imshow(detpic2,'parent',app.UIAxes4);
                title(app.UIAxes4,'Anh sau khi nhan dang');
                a = sprintf("Accept this person");
                app.TextArea_2.Value = [string(app.TextArea_2.Value);a];
                app.Lamp_3.Color = [0 1 0]; %Green
                
            else
                
                pic = imread(sprintf('%s%d.jpg',app.trainpathface,61));
                imshow(pic,'parent',app.UIAxes4);
                title(app.UIAxes4,'Anh sau khi nhan dang');
                a = sprintf("Reject this person");
                app.TextArea_2.Value = [string(app.TextArea_2.Value);a];
                app.Lamp_3.Color = [1 0 0];%Red
                
            end
            %             app.resultimg = imread(sprintf('%s%d.jpg',app.trainpath,indx)); %cho ra duoc anh match tot nhat
            %             imshow(app.resultimg,'parent',app.UIAxes2);
            
        end

        % Button pushed function: Clear
        function ClearButtonPushed2(app, event)
            app.TextArea_2.Value = '';
            app.Lamp_3.Color = [0.9 0.9 0.9];%Mau Xam
        end

        % Value changed function: NumTrainImg
        function NumTrainImgValueChanged(app, event)
            value = app.NumTrainImg.Value;
            app.NumTrain = value;
        end

        % Value changed function: NumEigenVal
        function NumEigenValValueChanged(app, event)
            value = app.NumEigenVal.Value;
            app.EigenValue = value;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed2(app, event)
            
            tic
            n = app.NumTrain;
            L = app.EigenValue;
            app.M=100; app.N=90; %Kich thuoc yeu cau cua anh
            M=100; N=90;
            X=zeros(n,(app.M*app.N)); %khoi tao ma tran X ban dau
            app.T=zeros(n,L); %Khoi tao ma tran T trong ko gian PCA
            app.TextArea_2.Value = [string(app.TextArea_2.Value);"Waiting for training each file in train folder"];
            for count=1:n
                file = sprintf('%s%d.jpg', app.trainpathface, count);
                app.TextArea_2.Value = [string(app.TextArea_2.Value);"Create train each image in train folder:";file];
                
                
                img = imread(file);
                
                FaceDetect = vision.CascadeObjectDetector;
                FaceDetect.MergeThreshold = 7 ;
                BB = step(FaceDetect, img);
                
                for i = 1 : size(BB, 1)
                    j = imcrop(img, BB(i, :));
                    
                end
                
                
                j=rgb2gray(j); %chuyen tu anh mau sang anh xam
                j=imresize(j,[app.M,app.N]); %chinh lai kich thuoc chuan cho anh
                X(count,:)=reshape(j,[1,app.M*app.N]); %chuyen anh ve dang 1D la 1xMN
            end
            Xb=X; %copy lai anh
            app.m=mean(X); %tinh gia tri trung binh cua cac anh
            for i=1:n
                X(i,:)=X(i,:)-app.m; %tinh phuong sai tu anh 1D
            end
            Q = (X'*X)/(n-1); %tim ma tran covariance
            [Evecm,Evalm] = eig(Q); %tinh gia tri eigen va vector eigen cua ma tran cov Q
            Eval = diag(Evalm); %dua cac gia tri eigen vo duong cheo ma tran
            [Evalsorted,Index]=sort(Eval,'descend'); %lam giam so cot eigen theo chieu giam dan
            Evecsorted=Evecm(:,Index);
            app.Ppca = Evecsorted(:,1:L); %ma tran pca sau khi giam so chieu
            
            for i=1:n
                app.T(i,:) = (Xb(i,:)-app.m)*app.Ppca; %chieu moi anh len PCA space
            end
            t = app.T;
            Ppca = app.Ppca;
            m = app.m;
            save('face.mat','t','Ppca','M','N','m');
            save('face.dat','t','Ppca','m');
            time = toc;
            app.TextArea_2.Value = [string(app.TextArea_2.Value);"Thoi gian train: ";time];
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: LoadnhButton
        function LoadnhButtonPushed(app, event)
            [filename,pathname]=uigetfile('*.*','Chon anh dau vao');
            filewithpath=strcat(pathname,filename);
            drawnow;
            figure(app.UIFigure);
            app.img=imread(filewithpath);
            img = app.img;


            FaceDetect = vision.CascadeObjectDetector;
            FaceDetect.MergeThreshold = 7 ;
            BB = step(FaceDetect, img);
            if isempty(BB)
                app.TextArea_2.Value = [string(app.TextArea_2.Value);'Khong co guong mat'];
            else
                for i = 1 : size(BB, 1)
                    J = imcrop(img, BB(i, :));
                    
                end
                
                
                imshow(J,'parent',app.UIAxes1);
            end
            
        end

        % Button pushed function: ExcelButton
        function ExcelButtonPushed2(app, event)
            Number_of_sheet = 1;
            Begin_range      = 'B11';
            
            writematrix(app.distance,app.Excelpath2,'sheet',Number_of_sheet,'Range',Begin_range);
            
            message = sprintf("Write to Excel %s, sheet: %d,begin address: %s",app.Excelpath2,Number_of_sheet,Begin_range);
            app.TextArea_2.Value = [string(app.TextArea_2.Value);message];
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Button pushed function: DngWebcamButton
        function DngWebcamButtonPushed(app, event)
            app.go = 0;
            cam = app.cam;
            delete (cam);
            clear cam;
            
        end

        % Button pushed function: XoaAnh
        function XoaAnhButtonPushed(app, event)
            cla(app.UIAxes1,'reset');
            cla(app.UIAxes2,'reset');
        end

        % Value changed function: Time
        function TimeValueChanged(app, event)
            value = app.Time.Value;
            app.Times = value;
        end

        % Button pushed function: ChupAnhTrain
        function ChupAnhTrainButtonPushed(app, event)
            for counter = app.From1:1:app.To1
                %             cam = webcam;
                app.cam.Resolution = '640x480';
                image = snapshot(app.cam);
                %             savename = strcat('C:\Users\Hi\Desktop\LVTN\Face_voice_recogition\TestDB\',num2str(counter),'.jpg');
                
                img = image;
                FaceDetect = vision.CascadeObjectDetector;
                FaceDetect.MergeThreshold = 7 ;
                BB = step(FaceDetect, img);
                if isempty(BB)
                    app.TextArea_2.Value = [string(app.TextArea_2.Value);'Khong co guong mat'];
                    break;
                else
                    %                 for i = 1 : size(BB, 1)
                    %                     J = imcrop(img, BB(i, :));
                    %
                    %                 end
                    
                    %                 app.img = J;
                    %                 imshow(J,'parent',app.UIAxes1);
                    savename = strcat('F:\LVTN\Face_Voice_Recogition\TrainDB\',num2str(counter),'.jpg');
                    imwrite(img,savename);
                end
            end
        end

        % Value changed function: From
        function FromValueChanged(app, event)
            value = app.From.Value;
            app.From1 = value;
        end

        % Value changed function: To
        function ToValueChanged(app, event)
            value = app.To.Value;
            app.To1 = value;
        end

        % Callback function
        function AnhChinhChuValueChanged(app, event)
            value = app.AnhChinhChu.Value;
            app.ChinhChu = value;
        end

        % Callback function
        function ExcelEditFieldValueChanged(app, event)
            value = app.ExcelEditField.Value;
            app.NhapExcel = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.RecordButton = uibutton(app.ControlPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = {'Record'; ''};
            app.PlotButton = uibutton(app.ControlPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SpectrumButton = uibutton(app.ControlPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = {'Spectrum'; ''};
            app.Load = uibutton(app.ControlPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.MFCC = uibutton(app.ControlPanel, 'push');
            app.MFCC.ButtonPushedFcn = createCallbackFcn(app, @MFCCButtonPushed, true);
            app.MFCC.Text = {'MFCC'; ''};
            app.ClearButton = uibutton(app.ControlPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Endcut = uibutton(app.ControlPanel, 'push');
            app.Endcut.ButtonPushedFcn = createCallbackFcn(app, @EndcutButtonPushed, true);
            app.Endcut.Text = 'Endpoint Detection';
            app.Sound = uibutton(app.ControlPanel, 'push');
            app.Sound.ButtonPushedFcn = createCallbackFcn(app, @SoundButtonPushed, true);
            app.Sound.Text = 'Sound';
            app.Excel = uibutton(app.ControlPanel, 'push');
            app.Excel.ButtonPushedFcn = createCallbackFcn(app, @ExcelButtonPushed, true);
            app.Excel.Text = 'Excel';
            app.PreemphCheckBox = uicheckbox(app.ControlPanel);
            app.PreemphCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreemphCheckBoxValueChanged, true);
            app.PreemphCheckBox.Text = {'Preemph'; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo BK_vien trang (1).png';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Speaker ID'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Hello World'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Original'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {''; ''};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.IdentificationPanel = uipanel(app.UIFigure);
            app.IdentificationPanel.Title = 'Identification';
            app.Train = uibutton(app.IdentificationPanel, 'push');
            app.Train.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.Train.Text = 'Train';
            app.MultiTest = uibutton(app.IdentificationPanel, 'push');
            app.MultiTest.ButtonPushedFcn = createCallbackFcn(app, @MultiTestButtonPushed, true);
            app.MultiTest.Text = 'Multiple Test';
            app.Save = uibutton(app.IdentificationPanel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save';
            app.SingleTest = uibutton(app.IdentificationPanel, 'push');
            app.SingleTest.ButtonPushedFcn = createCallbackFcn(app, @SingleTestButtonPushed, true);
            app.SingleTest.Text = 'Single Test';
            app.PasswordDetect = uibutton(app.IdentificationPanel, 'push');
            app.PasswordDetect.ButtonPushedFcn = createCallbackFcn(app, @PasswordDetectButtonPushed, true);
            app.PasswordDetect.Text = 'Password';
            app.Panel = uipanel(app.UIFigure);
            app.SpeakerIDEditFieldLabel = uilabel(app.Panel);
            app.SpeakerIDEditFieldLabel.Text = {'Speaker ID'; ''};
            app.SpeakerIDEditField = uieditfield(app.Panel, 'numeric');
            app.SpeakerIDEditField.ValueChangedFcn = createCallbackFcn(app, @SpeakerIDEditFieldValueChanged, true);
            app.CentroidEditFieldLabel = uilabel(app.Panel);
            app.CentroidEditFieldLabel.Text = 'Centroid';
            app.CentroidEditField = uieditfield(app.Panel, 'numeric');
            app.CentroidEditField.ValueChangedFcn = createCallbackFcn(app, @CentroidEditFieldValueChanged, true);
            app.NofSpeakerEditFieldLabel = uilabel(app.Panel);
            app.NofSpeakerEditFieldLabel.Text = 'N of Speaker';
            app.NofSpeakerEditField = uieditfield(app.Panel, 'numeric');
            app.NofSpeakerEditField.ValueChangedFcn = createCallbackFcn(app, @NofSpeakerEditFieldValueChanged, true);
            app.PasswordHTML = uihtml(app.Panel);
            app.PasswordHTML.HTMLSource = 'passwordEdit.html';
            app.PasswordHTML.DataChangedFcn = createCallbackFcn(app, @PasswordHTMLDataChanged, true);
            app.PasswordHTML.Data = 123456;
            app.password_label = uilabel(app.Panel);
            app.password_label.Text = 'Password';
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time Record';
            app.Time = uieditfield(app.Panel, 'numeric');
            app.Time.ValueChangedFcn = createCallbackFcn(app, @TimeValueChanged, true);
            app.ControlFacePanel = uipanel(app.UIFigure);
            app.ControlFacePanel.Title = 'ControlFace';
            app.ChonAnh = uibutton(app.ControlFacePanel, 'push');
            app.ChonAnh.ButtonPushedFcn = createCallbackFcn(app, @ChonAnhButtonPushed, true);
            app.ChonAnh.Text = 'Chụp Ảnh';
            app.NhanDang = uibutton(app.ControlFacePanel, 'push');
            app.NhanDang.ButtonPushedFcn = createCallbackFcn(app, @NhanDangButtonPushed, true);
            app.NhanDang.Text = 'Nhận Dạng';
            app.XoaAnh = uibutton(app.ControlFacePanel, 'push');
            app.XoaAnh.ButtonPushedFcn = createCallbackFcn(app, @XoaAnhButtonPushed, true);
            app.XoaAnh.Text = 'Xóa Ảnh';
            app.Clear = uibutton(app.ControlFacePanel, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed2, true);
            app.Clear.Text = 'Clear';
            app.WebcamButton = uibutton(app.ControlFacePanel, 'push');
            app.WebcamButton.ButtonPushedFcn = createCallbackFcn(app, @WebcamButtonPushed, true);
            app.WebcamButton.Text = 'Webcam';
            app.LoadnhButton = uibutton(app.ControlFacePanel, 'push');
            app.LoadnhButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnhButtonPushed, true);
            app.LoadnhButton.Text = 'Load Ảnh';
            app.ExcelButton = uibutton(app.ControlFacePanel, 'push');
            app.ExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelButtonPushed2, true);
            app.ExcelButton.Text = 'Excel';
            app.DngWebcamButton = uibutton(app.ControlFacePanel, 'push');
            app.DngWebcamButton.ButtonPushedFcn = createCallbackFcn(app, @DngWebcamButtonPushed, true);
            app.DngWebcamButton.Text = 'Dừng Webcam';
            app.TrainFacePanel = uipanel(app.UIFigure);
            app.TrainFacePanel.Title = 'TrainFace';
            app.TrainButton = uibutton(app.TrainFacePanel, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed2, true);
            app.TrainButton.Text = 'Train';
            app.SnhTrainEditFieldLabel = uilabel(app.TrainFacePanel);
            app.SnhTrainEditFieldLabel.Text = 'Số Ảnh Train';
            app.NumTrainImg = uieditfield(app.TrainFacePanel, 'numeric');
            app.NumTrainImg.ValueChangedFcn = createCallbackFcn(app, @NumTrainImgValueChanged, true);
            app.SEigenValueEditFieldLabel = uilabel(app.TrainFacePanel);
            app.SEigenValueEditFieldLabel.Text = 'Số Eigen Value';
            app.NumEigenVal = uieditfield(app.TrainFacePanel, 'numeric');
            app.NumEigenVal.ValueChangedFcn = createCallbackFcn(app, @NumEigenValValueChanged, true);
            app.ChupAnhTrain = uibutton(app.TrainFacePanel, 'push');
            app.ChupAnhTrain.ButtonPushedFcn = createCallbackFcn(app, @ChupAnhTrainButtonPushed, true);
            app.ChupAnhTrain.Text = 'Chụp Ảnh Train';
            app.TrainFromNoLabel = uilabel(app.TrainFacePanel);
            app.TrainFromNoLabel.Text = 'Train From No.';
            app.From = uieditfield(app.TrainFacePanel, 'numeric');
            app.From.ValueChangedFcn = createCallbackFcn(app, @FromValueChanged, true);
            app.ToNoLabel = uilabel(app.TrainFacePanel);
            app.ToNoLabel.Text = 'To No.';
            app.To = uieditfield(app.TrainFacePanel, 'numeric');
            app.To.ValueChangedFcn = createCallbackFcn(app, @ToValueChanged, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'WEBCAM')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Ảnh chụp từ Webcam')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = {''; ''};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.HTHNGNHNDNGNGIBNGKHUNMTVGINGNILabel = uilabel(app.UIFigure);
            app.HTHNGNHNDNGNGIBNGKHUNMTVGINGNILabel.Text = 'HỆ THỐNG NHẬN DẠNG NGƯỜI BẰNG KHUÔN MẶT VÀ GIỌNG NÓI';
            app.LampPanel = uipanel(app.UIFigure);
            app.LampPanel.Title = 'Lamp';
            app.Lamp_3 = uilamp(app.LampPanel);
            app.Lamp = uilamp(app.LampPanel);
            app.Lamp_2 = uilamp(app.LampPanel);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Ảnh Sau Khi Nhận Dạng')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FaceVoiceRecogition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end